classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        LoadFileButton  matlab.ui.control.Button
        Label           matlab.ui.control.Label
        EditField       matlab.ui.control.NumericEditField
        OriginSeries    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile("*.csv", "Выберите csv файл с данными");
            T = readtable(strcat(path, file));
            y = normalize(T.RCORR_P);
            N = length(T.RCORR_P); 
            t = T.start_date_time;
            %app.OriginSeries.XAxis = t;
            %app.OriginSeries. = y;
            figure('Name', strcat('Исходный ряд. ', "abcd"))
            plot(t, y, 'b-');
            plot(app.OriginSeries, t, y, 'b-');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginSeries = uiaxes(app.UIFigure);
            title(app.OriginSeries, 'Title')
            xlabel(app.OriginSeries, 'X')
            ylabel(app.OriginSeries, 'Y')
            zlabel(app.OriginSeries, 'Z')
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Длина окна';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Загрузить файл';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end